classdef app5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        WatermarkStatusLabel     matlab.ui.control.Label
        AlreadyWatermarkedHostImageWatermarkuploadButton  matlab.ui.control.Button
        GUIBYSREENESHLabel       matlab.ui.control.Label
        DCTBasedImageWatermarkingSystemLabel  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        DropDown                 matlab.ui.control.DropDown
        DropDownLabel            matlab.ui.control.Label
        CalculateAccuracyButton  matlab.ui.control.Button
        ExtractWatermarkButton   matlab.ui.control.Button
        AddNoiseButton           matlab.ui.control.Button
        EmbedWatermarkButton     matlab.ui.control.Button
        LoadWatermarkButton      matlab.ui.control.Button
        LoadCoverImageButton     matlab.ui.control.Button
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end


        properties (Access = private)
    cover
    wm
    wmBits
    watermarked
    noisy
    SelectedNoise
    extracted
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadCoverImageButton
        function LoadCoverImageButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Select Cover Image');
    if isequal(filename, 0)
        return;
    end
    
    img = imread(fullfile(pathname,filename));  
    if size(img, 3)==3
        img = rgb2gray(img);
    end
    
    img = imresize(img, [256 256]);
    app.cover = img;
    imshow(app.cover,'Parent', app.UIAxes);
    title(app.UIAxes, 'Cover Image');


        end

        % Button pushed function: LoadWatermarkButton
        function LoadWatermarkButtonPushed(app, event)
        [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Select Cover Image');
    if isequal(filename, 0)
        return;
    end
    img = imread(fullfile(pathname,filename));  
if size(img, 3)==3
    img = rgb2gray(img);
end
    
% Keep the original for display
imshow(img,'Parent', app.UIAxes_2);
title(app.UIAxes_2,'Watermark Image');  

% Prepare a small, binarized, resized copy for embedding
img_bin = imbinarize(img);
img_bin = imresize(img_bin, [32 32]);
app.wm = img_bin;
app.wmBits = img_bin(:);


        end

        % Button pushed function: EmbedWatermarkButton
        function EmbedWatermarkButtonPushed(app, event)
            if isempty(app.cover) || isempty(app.wm)
        uialert(app.UIFigure,'Load cover and watermark first!','Error'); 
        return;
    end
    
    watermarked = zeros(size(app.cover)); 
    bitIndex = 1;
    for row = 1:8:256
        for col = 1:8:256
            block = app.cover(row:row+7, col:col+7);
            dctBlock = dct2(block);
            if app.wmBits(bitIndex) == 1
                dctBlock(5,5) = dctBlock(5,5) + 10;
            else
                dctBlock(5,5) = dctBlock(5,5) - 10;
            end
            watermarked(row:row+7, col:col+7) = idct2(dctBlock);
            bitIndex = bitIndex + 1;
            if bitIndex > length(app.wmBits)
                break;
            end
        end
    end
    
    app.watermarked = uint8(watermarked);
    imshow(app.watermarked,'Parent', app.UIAxes_3);

    title(app.UIAxes_3, 'Watermarked Image'); 

        end

        % Button pushed function: AddNoiseButton
        function AddNoiseButtonPushed(app, event)
        
    if isempty(app.watermarked)
        uialert(app.UIFigure, 'Embed watermark first!', 'Error');
        return;
    end
    
    % Reset noisy image to original watermarked version
    app.noisy = app.watermarked; % Reset to original before applying new noise
    
    % Get selected noise type
    type = app.DropDown.Value;
    
    % Apply the selected noise
    switch type
        case 'None'
            app.noisy = uint8(app.watermarked); % No noise
        case 'Gaussian'
            app.noisy = imnoise(uint8(app.watermarked), 'gaussian', 0, 0.005);
        case 'Salt&pepper'
            app.noisy = imnoise(uint8(app.watermarked), 'salt & pepper', 0.05);
        case 'Poisson'
            app.noisy = imnoise(uint8(app.watermarked), 'poisson');
        case 'Speckle'
            app.noisy = imnoise(uint8(app.watermarked), 'speckle', 0.04);
    end
    
    % Display the noisy image
    imshow(app.noisy, 'Parent', app.UIAxes_3);
    title(app.UIAxes_3, ['Watermarked with ', type, ' Noise']);
    app.SelectedNoise = type;

        end

        % Button pushed function: ExtractWatermarkButton
        function ExtractWatermarkButtonPushed(app, event)
           
    if isempty(app.watermarked)
        uialert(app.UIFigure,'Embed first!','Error'); 
        return;
    end
    
    bitIndex = 1;
    extracted = zeros(1024,1);
    for row = 1:8:256
        for col = 1:8:256
            block = app.watermarked(row:row+7, col:col+7);
            dctBlock = dct2(double(block));  
            if dctBlock(5,5) > 0
                extracted(bitIndex) = 1;
            else
                extracted(bitIndex) = 0;
            end
            bitIndex = bitIndex + 1;
            if bitIndex > 1024
                break;
            end
        end
    end
    
    % Reshape to 32x32 first
    tiny = reshape(extracted, [32 32]);
    % Resize back to original dimensions (say 256x256) 
    extracted_full = imresize(logical(tiny), [256 256], 'nearest');    
    
    % Display in GUI
    imshow(extracted_full,'Parent', app.UIAxes_4);
    title(app.UIAxes_4, 'Extracted Watermark (Restored)')
    
    % Accuracy
    bitErrors = sum(xor(app.wmBits, extracted));  
    accuracy = (1 - bitErrors/length(app.wmBits)) * 100;
    uilabel(app.UIFigure,'Text',['Accuracy: ' num2str(accuracy,'%.2f') '%'],...
        'Position',[400, 100, 200, 30]);


        end

        % Button pushed function: CalculateAccuracyButton
        function CalculateAccuracyButtonPushed(app, event)
            if isempty(app.noisy)
        uialert(app.UIFigure,'Please apply or select a noise first.','Error'); 
        return;
    end
    
    % Extract watermark from noisy instead of original
    bitIndex = 1;
    extracted = zeros(1024,1);
    for row = 1:8:256
        for col = 1:8:256
            block = app.noisy(row:row+7, col:col+7);
            dctBlock = dct2(double(block));  
            if dctBlock(5,5) > 0
                extracted(bitIndex) = 1;
            else
                extracted(bitIndex) = 0;
            end
            bitIndex = bitIndex + 1;
            if bitIndex > 1024
                break;
            end
        end
    end


    % Accuracy against original
    bitErrors = sum(xor(app.wmBits, extracted));  
    accuracy = (1 - bitErrors / length(app.wmBits)) * 100;
    app.Label.Text = ['Accuracy (' app.SelectedNoise '): ' num2str(accuracy,'%.2f') '%'];


        end

        % Button pushed function: 
        % AlreadyWatermarkedHostImageWatermarkuploadButton
        function ValidateWatermarkButtonPushed(app, event)
            
    % 1. Select already watermarked image
    [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Select Watermarked Image');
    if isequal(filename, 0)
        return;
    end
    
    watermarked = imread(fullfile(pathname, filename));  
    if size(watermarked, 3) == 3
        watermarked = rgb2gray(watermarked);
    end
    watermarked = imresize(watermarked, [256 256]);
    
    % 2. Select original watermark (small binary image)
    [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Select Original Watermark');
    if isequal(filename, 0)
        return;
    end
    
    original_wm = imread(fullfile(pathname, filename));  
    if size(original_wm, 3) == 3
        original_wm = rgb2gray(original_wm);
    end
    original_wm_bin = imbinarize(original_wm);
    original_wm_bin = imresize(original_wm_bin, [32 32]); % Ensure 32x32 binary
    original_bits = original_wm_bin(:); % Flatten to 1024x1 vector
    
    % 3. Extract watermark from the watermarked image
    extracted = zeros(1024, 1); % Initialize extracted bits
    bitIndex = 1;
    
    for row = 1:8:256
        for col = 1:8:256
            if bitIndex > 1024
                break; % Stop if all bits are extracted
            end
            
            % Get 8x8 block and compute DCT
            block = watermarked(row:row+7, col:col+7);
            dctBlock = dct2(double(block));
            
            % Dynamic thresholding (more robust than fixed >0)
            avg_val = mean(abs(dctBlock(:)));
            if dctBlock(5,5) > avg_val
                extracted(bitIndex) = 1;
            else
                extracted(bitIndex) = 0;
            end
            
            bitIndex = bitIndex + 1;
        end
    end
    
    % 4. Compare extracted vs. original watermark
    % (A) Bit accuracy
    matching_bits = sum(extracted == original_bits);
    bit_accuracy = (matching_bits / 1024) * 100;
    
    % (B) Correlation (for visual similarity)
    extracted_2d = reshape(extracted, [32 32]); % Reshape to 32x32
    correlation = corr2(original_wm_bin, extracted_2d);
    
    % 5. Display results
    if bit_accuracy > 90 || correlation > 0.7
        app.WatermarkStatusLabel.Text = sprintf('Watermark FOUND (%.2f%% match, Corr: %.2f)', bit_accuracy, correlation);
        app.WatermarkStatusLabel.BackgroundColor = [0.39 0.83 0.39]; % Green
    else
        app.WatermarkStatusLabel.Text = sprintf('Watermark NOT FOUND (%.2f%% match, Corr: %.2f)', bit_accuracy, correlation);
        app.WatermarkStatusLabel.BackgroundColor = [0.97 0.39 0.39]; % Red
    end
    
    % Show extracted watermark in UIAxes_4
    imshow(extracted_2d, 'Parent', app.UIAxes_4);
    title(app.UIAxes_4, 'Extracted Watermark');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cover Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'watermark')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Watermarked')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Extracted')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.LoadCoverImageButton = uibutton(app.UIFigure, 'push');
            app.LoadCoverImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCoverImageButtonPushed, true);
            app.LoadCoverImageButton.Text = 'Load Cover Image';
            app.LoadWatermarkButton = uibutton(app.UIFigure, 'push');
            app.LoadWatermarkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWatermarkButtonPushed, true);
            app.LoadWatermarkButton.Text = 'Load Watermark';
            app.EmbedWatermarkButton = uibutton(app.UIFigure, 'push');
            app.EmbedWatermarkButton.ButtonPushedFcn = createCallbackFcn(app, @EmbedWatermarkButtonPushed, true);
            app.EmbedWatermarkButton.Text = 'Embed Watermark';
            app.AddNoiseButton = uibutton(app.UIFigure, 'push');
            app.AddNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButtonPushed, true);
            app.AddNoiseButton.Text = 'Add Noise';
            app.ExtractWatermarkButton = uibutton(app.UIFigure, 'push');
            app.ExtractWatermarkButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractWatermarkButtonPushed, true);
            app.ExtractWatermarkButton.Text = 'Extract Watermark';
            app.CalculateAccuracyButton = uibutton(app.UIFigure, 'push');
            app.CalculateAccuracyButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateAccuracyButtonPushed, true);
            app.CalculateAccuracyButton.Text = 'Calculate Accuracy';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'None', 'Gaussian', 'Salt&pepper', 'Poisson', 'speckle'};
            app.DropDown.Value = 'None';
            app.Label = uilabel(app.UIFigure);
            app.DCTBasedImageWatermarkingSystemLabel = uilabel(app.UIFigure);
            app.DCTBasedImageWatermarkingSystemLabel.Text = 'DCT-Based Image Watermarking System';
            app.GUIBYSREENESHLabel = uilabel(app.UIFigure);
            app.GUIBYSREENESHLabel.Text = 'GUI BY SREENESH';
            app.AlreadyWatermarkedHostImageWatermarkuploadButton = uibutton(app.UIFigure, 'push');
            app.AlreadyWatermarkedHostImageWatermarkuploadButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateWatermarkButtonPushed, true);
            app.AlreadyWatermarkedHostImageWatermarkuploadButton.Text = {'Already Watermarked '; 'Host Image + Watermark'; 'upload Button'};
            app.WatermarkStatusLabel = uilabel(app.UIFigure);
            app.WatermarkStatusLabel.Text = 'WatermarkStatusLabel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end